classdef SBTi_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TemperatureScoreTab             matlab.ui.container.Tab
        DisplaycolumnsPanel             matlab.ui.container.Panel
        SelectColumnsListBox            matlab.ui.control.ListBox
        PortfolioCoveragePanel          matlab.ui.container.Panel
        PortfolioCoverageValue          matlab.ui.control.Label
        AggregatedTemperatureScorePanel  matlab.ui.container.Panel
        AggTemperatureScore             matlab.ui.control.Label
        AggScopeDropDown                matlab.ui.control.DropDown
        ScopeDropDown_2Label            matlab.ui.control.Label
        AggTimeFrameDropDown            matlab.ui.control.DropDown
        TimeFrameDropDown_2Label        matlab.ui.control.Label
        Gauge                           matlab.ui.control.SemicircularGauge
        InputPanel                      matlab.ui.container.Panel
        ScopeListBox                    matlab.ui.control.ListBox
        ScopeListBoxLabel               matlab.ui.control.Label
        TimeframeListBox                matlab.ui.control.ListBox
        TimeframeListBoxLabel           matlab.ui.control.Label
        AggregationMethodDropDown       matlab.ui.control.DropDown
        AggregationMethodDropDownLabel  matlab.ui.control.Label
        DefaultScoreEditField           matlab.ui.control.EditField
        DefaultScoreEditFieldLabel      matlab.ui.control.Label
        comapnyDataFile                 matlab.ui.control.Label
        SelectcompanydataButton         matlab.ui.control.Button
        porfolioFile                    matlab.ui.control.Label
        SelectPortfolioDataButton       matlab.ui.control.Button
        ExporttoworkspaceTscoreButton   matlab.ui.control.Button
        ComputeTemperatureScoreButton   matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SectorRegionAnalysisTab         matlab.ui.container.Tab
        HeatMap                         matlab.ui.container.Panel
        AggregatedTemperatureScorePanel_2  matlab.ui.container.Panel
        GroupAggTempScore               matlab.ui.control.Label
        groupGauge                      matlab.ui.control.SemicircularGauge
        GroupbyDropDown                 matlab.ui.control.DropDown
        GroupbyDropDownLabel            matlab.ui.control.Label
        GroupScopeDropDown              matlab.ui.control.DropDown
        ScopeDropDownLabel              matlab.ui.control.Label
        GroupTimeFrameDropDown          matlab.ui.control.DropDown
        TimeFrameDropDownLabel          matlab.ui.control.Label
        PieContri                       matlab.ui.control.UIAxes
        PieInvest                       matlab.ui.control.UIAxes
        GroupScoreBarUIAxes             matlab.ui.control.UIAxes
        ScenarioAnalysisTab             matlab.ui.container.Tab
        ExporttoWorkspaceScenarioButton  matlab.ui.control.Button
        ScenarioTable                   matlab.ui.control.Table
        ScenariosPanel                  matlab.ui.container.Panel
        RemoveScenarioButton            matlab.ui.control.Button
        ScenariosListBox                matlab.ui.control.ListBox
        ScenarioInputsPanel             matlab.ui.container.Panel
        AddScenarioButton               matlab.ui.control.Button
        AggregationMethodDropDown_2     matlab.ui.control.DropDown
        AggregationMethodDropDown_2Label  matlab.ui.control.Label
        EngagementTypeDropDown          matlab.ui.control.DropDown
        EngagementTypeDropDownLabel     matlab.ui.control.Label
        ScenarioTypeDropDown            matlab.ui.control.DropDown
        ScenarioTypeDropDownLabel       matlab.ui.control.Label
        ScenarioNameEditField           matlab.ui.control.EditField
        ScenarioNameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        SBTi_options = struct; % Description
        amended_portfolio % Description
        aggregated_scores % Description
        scenarioOptions = struct; % Description
        provider % Description
        df_portfolio % Description
        amended_portfolio_subset % Description
        groupOptions = struct; % Description
        baseAggScores % Description
    end
    
    methods (Access = private)
        
        function updateGroupPlots(app)
            options = app.SBTi_options;
            options.grouping = app.groupOptions.grouping;
            
            % Assuming 'region' column present in the company
            % financials data
            
            [amended_portfolio, aggregated_scores, ~, ~] = SBTi_MATLAB(app,options);
            [sector_temp_scores, sector_names, sector_contributions, sector_investments] = ...
                SBTi_groupStat(app,aggregated_scores, amended_portfolio, app.groupOptions.time_frame{1}, app.groupOptions.scope{1}, app.groupOptions.grouping);
            
            % bar chart - T Score
            cla(app.GroupScoreBarUIAxes)
            app.GroupScoreBarUIAxes.NextPlot = 'replace';
            bar(app.GroupScoreBarUIAxes,sector_names,sector_temp_scores)
            yline(app.GroupScoreBarUIAxes,2,'r--','LineWidth',2)
            title(app.GroupScoreBarUIAxes,"Temperature Score vs category")
            xlabel(app.GroupScoreBarUIAxes,'Category')
            ylabel(app.GroupScoreBarUIAxes,'Temperature score')
            
            % bar chart - GHG emission
            
            % pie charts
            app.PieInvest.NextPlot = 'replace';
            pie(app.PieInvest,sector_investments)
            title(app.PieInvest,'Investment')
            legend(app.PieInvest,sector_names, 'Location', 'eastoutside')
            title(app.PieContri,'Temperature score contribution')
            pie(app.PieContri,sector_contributions)
        end
        
        % 
        function [amended_portfolio, aggregated_scores_obj, coverage, AggScoresTable] = SBTi_MATLAB(app,options)
            
            [amended_portfolio, aggregated_scores_obj, coverage] = SBTi_wrapper.calTemperatureScore(options);

            [scope_list,time_frame_list,score_list] = SBTi_wrapper.getScoreForEachTimeFrameAndScope(aggregated_scores_obj,...
                app.SBTi_options.scopes,app.SBTi_options.time_frames);
            
            name_array = repmat("Base", length(score_list), 1);

            AggScoresTable = [cellstr(name_array) cellstr(scope_list) cellstr(time_frame_list) num2cell(score_list)];
        end    
        
        % 
        function scenario_table_data = SBTi_scenario(app,scenarioName)
            [scope_list,time_frame_list,score_list] = SBTi_wrapper.calTemperatureScoreForScenario(app.scenarioOptions);
            
            name_list = cell(length(score_list),1);
            name_list(:) = {scenarioName};
            
            scenario_table_data = [name_list scope_list time_frame_list num2cell(score_list)];
        end
        
        
        function [sector_temp_scores, sector_names, sector_contributions, sector_investments] = SBTi_groupStat(app,aggregated_scores, amended_portfolio, time_frame, scope, grouping)
            
            company_contributions = SBTi_wrapper.collect_company_contributions(aggregated_scores, amended_portfolio, time_frame, scope, grouping);
            [sector_temp_scores, sector_names, sector_contributions, sector_investments] = SBTi_wrapper.groupStatistics(aggregated_scores, company_contributions, time_frame, scope, grouping);
            
        end
        
        function updateMainGauge(app)
            temp = app.ScenarioTable.Data(:,4);
            temp2 = temp(strcmp(app.AggTimeFrameDropDown.Value,app.ScenarioTable.Data(:,3)) &...
                strcmp(app.AggScopeDropDown.Value,app.ScenarioTable.Data(:,2)) &...
                strcmp('Base',app.ScenarioTable.Data(:,1)));
            app.Gauge.Value = temp2{1};
            app.AggTemperatureScore.Text = num2str(temp2{1});
        end
        
        function updateGroupGauge(app)
            temp = app.ScenarioTable.Data(:,4);
            temp2 = temp(strcmp(app.GroupTimeFrameDropDown.Value,app.ScenarioTable.Data(:,3)) &...
                strcmp(app.GroupScopeDropDown.Value,app.ScenarioTable.Data(:,2)) &...
                strcmp('Base',app.ScenarioTable.Data(:,1)));
            app.groupGauge.Value = temp2{1};
            app.GroupAggTempScore.Text = [num2str(round(temp2{1},2)) char(176) 'C'];
        end
        
        function updateHeatMap(app)
            options = app.SBTi_options;
            options.grouping = {'region','sector'};
            [amended_portfolio_df, aggregated_scores] = SBTi_MATLAB(app,options);
            [sector_temp_scores, sector_names, sector_contributions, sector_investments] = ...
                SBTi_groupStat(app,aggregated_scores, amended_portfolio_df, app.groupOptions.time_frame{1}, app.groupOptions.scope{1}, {'region','sector'});               
            region = sector_names(:,1);
            sector = sector_names(:,2);
            sector_temp_scores = sector_temp_scores';
            tempTable = table(region,sector,sector_temp_scores);
            heatmap(app.HeatMap,tempTable,'region','sector',...
                'ColorVariable','sector_temp_scores','ColorMethod','none','Colormap',flipud(autumn))  
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % guage color
%             app.gaugeColor.ImageSource = 'gauge_color.png';                        

            app.SBTi_options.fallback_score = str2num(app.DefaultScoreEditField.Value);
            app.SBTi_options.PortfolioAggregationMethod = app.AggregationMethodDropDown.Value;
            app.SBTi_options.time_frames = string(app.TimeframeListBox.Value);
            app.SBTi_options.scopes = string(app.ScopeListBox.Value);
            
            
            % scenarios
            app.scenarioOptions.name = app.ScenarioNameEditField.Value;
            app.scenarioOptions.scenarioType = app.ScenarioTypeDropDown.Value;
            app.scenarioOptions.engagementType = app.EngagementTypeDropDown.Value;
            app.scenarioOptions.aggregationMethod = app.AggregationMethodDropDown_2.Value;
            % Scenario input from the Temperature Score tab
            app.scenarioOptions.time_frames = app.TimeframeListBox.Value;
            app.scenarioOptions.scopes = app.ScopeListBox.Value;
            app.scenarioOptions.fallback_score = str2num(app.DefaultScoreEditField.Value);
            
            
        end

        % Button pushed function: SelectPortfolioDataButton
        function SelectPortfolioDataButtonPushed(app, event)
            [file,path] = uigetfile([fullfile(fileparts(mfilename('fullpath')), 'data'), '/*.csv']);
            path = strrep(path,'\','/');
            app.SBTi_options.portfolioCSV = [path file];
            app.porfolioFile.Text = file;
            app.scenarioOptions.portfolioCSV = [path file];
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: SelectcompanydataButton
        function SelectcompanydataButtonPushed(app, event)
            [file,path] = uigetfile([fullfile(fileparts(mfilename('fullpath')), 'data'),'/*.xlsx']);
            path = strrep(path,'\','/');
            app.SBTi_options.dataProviderXLSX = [path file];
            app.comapnyDataFile.Text = file; 
            app.scenarioOptions.dataProviderXLSX = [path file];
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: DefaultScoreEditField
        function DefaultScoreEditFieldValueChanged(app, event)
            value = app.DefaultScoreEditField.Value;
            app.SBTi_options.fallback_score = str2num(value);
        end

        % Value changed function: AggregationMethodDropDown
        function AggregationMethodDropDownValueChanged(app, event)
            value = app.AggregationMethodDropDown.Value;
            app.SBTi_options.PortfolioAggregationMethod = value;
        end

        % Value changed function: TimeframeListBox
        function TimeframeListBoxValueChanged(app, event)
            value = app.TimeframeListBox.Value;
            app.SBTi_options.time_frames = string(value);
        end

        % Value changed function: ScopeListBox
        function ScopeListBoxValueChanged(app, event)
            value = app.ScopeListBox.Value;
            app.SBTi_options.scopes = string(value);
        end

        % Button pushed function: ComputeTemperatureScoreButton
        function ComputeTemperatureScoreButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Computing Temperature Score', 'Indeterminate',"on");
            [amended_portfolio, aggregated_scores, coverage, baseAggScores] = SBTi_MATLAB(app,app.SBTi_options);
            app.amended_portfolio = amended_portfolio;
            app.aggregated_scores = aggregated_scores;
            app.UITable.Data = amended_portfolio;
            app.UITable.ColumnName = amended_portfolio.Properties.VariableNames;
            app.PortfolioCoverageValue.Text = [num2str(round(coverage,1)) '%'];
            app.baseAggScores = baseAggScores;
            
            % Calculate temperature score for base scenario
            app.scenarioOptions.time_frames = app.TimeframeListBox.Value;
            app.scenarioOptions.scopes = app.ScopeListBox.Value;
            app.scenarioOptions.fallback_score = str2num(app.DefaultScoreEditField.Value);

            % Add data to scenario table
            app.ScenarioTable.Data = app.baseAggScores;
            % save the portfolio and companies information
            % as a property
            
            % Set available time frame and scopes for guage
            app.AggTimeFrameDropDown.Items = app.TimeframeListBox.Value;
            app.AggScopeDropDown.Items = app.ScopeListBox.Value;
            
            % Set available time frame and scopes for group visualization
            app.GroupTimeFrameDropDown.Items = app.TimeframeListBox.Value;
            app.GroupScopeDropDown.Items = app.ScopeListBox.Value;

            
            % Set the main gauge for the first scope and time
            % frame
            app.AggTimeFrameDropDown.Value = app.SBTi_options.time_frames(1);
            app.AggScopeDropDown.Value = app.SBTi_options.scopes(1);
            temp = app.ScenarioTable.Data(:,4);
            temp2 = temp(strcmp(app.AggTimeFrameDropDown.Value,app.ScenarioTable.Data(:,3)) &...
                strcmp(app.AggScopeDropDown.Value,app.ScenarioTable.Data(:,2)) &...
                strcmp('Base',app.ScenarioTable.Data(:,1)));
            app.Gauge.Value = temp2{1};
            app.AggTemperatureScore.Text = [num2str(round(temp2{1},2)) char(176) 'C'];
            
            % set guage on group page
            app.groupGauge.Value = temp2{1};
            app.GroupAggTempScore.Text = [num2str(round(temp2{1},2)) char(176) 'C'];

            
            % Display columns in list box
            app.SelectColumnsListBox.Items = app.UITable.ColumnName;
            
            % create group score visulazition for base ('region') case
            if ismember('region',app.amended_portfolio.Properties.VariableNames)
                app.groupOptions.grouping = {'region'};
                app.groupOptions.time_frame = app.SBTi_options.time_frames(1);
                app.groupOptions.scope = app.SBTi_options.scopes(1);
                updateGroupPlots(app)
                updateHeatMap(app)
            end
            
            % Add heat map for sector-region
            
            close(d)
        end

        % Button pushed function: ExporttoworkspaceTscoreButton
        function ExporttoworkspaceTscoreButtonPushed(app, event)
            if isempty(app.amended_portfolio_subset)
                assignin('base','amended_portfolio',app.amended_portfolio);
            else
                assignin('base','amended_portfolio',app.amended_portfolio_subset);
            end
            assignin('base','aggregated_scores',app.aggregated_scores);
        end

        % Button pushed function: AddScenarioButton
        function AddScenarioButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Computing Temperature Score for scenario','Indeterminate',"on");
            app.scenarioOptions.name = app.ScenarioNameEditField.Value;
            app.scenarioOptions.scenarioType = app.ScenarioTypeDropDown.Value;
            app.scenarioOptions.engagementType = app.EngagementTypeDropDown.Value;
            app.scenarioOptions.aggregationMethod = app.AggregationMethodDropDown_2.Value;
            % time_frames, scopes and fallback_score are
            % same as what was stored for T score calc
            scenario_table_data = SBTi_scenario(app,app.scenarioOptions.name);
            old_data = app.ScenarioTable.Data;
            new_data = [old_data;scenario_table_data];
            app.ScenarioTable.Data = new_data;
            app.ScenariosListBox.Items = [app.ScenariosListBox.Items';{app.scenarioOptions.name}];
        end

        % Button pushed function: RemoveScenarioButton
        function RemoveScenarioButtonPushed(app, event)
            rmIdx1 = ismember(app.ScenarioTable.Data(:,1),app.ScenariosListBox.Value);
            app.ScenarioTable.Data(rmIdx1,:) = []; 
            rmIdx2 = ismember(app.ScenariosListBox.Items,app.ScenariosListBox.Value);
            app.ScenariosListBox.Items(rmIdx2) = [];
        end

        % Button pushed function: ExporttoWorkspaceScenarioButton
        function ExporttoWorkspaceScenarioButtonPushed(app, event)
            assignin('base','scenario_table_data',cell2table(app.ScenarioTable.Data,"VariableNames",app.ScenarioTable.ColumnName));
        end

        % Value changed function: AggTimeFrameDropDown
        function AggTimeFrameDropDownValueChanged(app, event)
            updateMainGauge(app)
        end

        % Value changed function: AggScopeDropDown
        function AggScopeDropDownValueChanged(app, event)
            updateMainGauge(app)
        end

        % Value changed function: SelectColumnsListBox
        function SelectColumnsListBoxValueChanged(app, event)
            idx = ismember(app.SelectColumnsListBox.Items,app.SelectColumnsListBox.Value);
            app.UITable.Data = app.amended_portfolio(:,idx);
            app.UITable.ColumnName = app.amended_portfolio.Properties.VariableNames(idx);
            app.amended_portfolio_subset = app.amended_portfolio(:,idx);
        end

        % Value changed function: GroupbyDropDown
        function GroupbyDropDownValueChanged(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Updating plots','Indeterminate',"on");
            app.groupOptions.grouping = {app.GroupbyDropDown.Value};
            updateGroupPlots(app)
            close(d)
        end

        % Value changed function: GroupTimeFrameDropDown
        function GroupTimeFrameDropDownValueChanged(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Updating plots','Indeterminate',"on");
            app.groupOptions.time_frame = {app.GroupTimeFrameDropDown.Value};
            updateGroupPlots(app)
            updateGroupGauge(app)
            updateHeatMap(app)
            close(d)
        end

        % Value changed function: GroupScopeDropDown
        function GroupScopeDropDownValueChanged(app, event)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Updating plots','Indeterminate',"on");
            app.groupOptions.scope = {app.GroupScopeDropDown.Value};
            updateGroupPlots(app)
            updateGroupGauge(app)
            updateHeatMap(app)
            close(d)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TemperatureScoreTab = uitab(app.TabGroup);
            app.TemperatureScoreTab.Title = 'Temperature Score';
            app.UITable = uitable(app.TemperatureScoreTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.ComputeTemperatureScoreButton = uibutton(app.TemperatureScoreTab, 'push');
            app.ComputeTemperatureScoreButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeTemperatureScoreButtonPushed, true);
            app.ComputeTemperatureScoreButton.Text = 'Compute Temperature Score';
            app.ExporttoworkspaceTscoreButton = uibutton(app.TemperatureScoreTab, 'push');
            app.ExporttoworkspaceTscoreButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoworkspaceTscoreButtonPushed, true);
            app.ExporttoworkspaceTscoreButton.Text = 'Export to workspace';
            app.InputPanel = uipanel(app.TemperatureScoreTab);
            app.InputPanel.Title = 'Input';
            app.SelectPortfolioDataButton = uibutton(app.InputPanel, 'push');
            app.SelectPortfolioDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPortfolioDataButtonPushed, true);
            app.SelectPortfolioDataButton.Text = 'Select Portfolio Data';
            app.porfolioFile = uilabel(app.InputPanel);
            app.porfolioFile.Text = '';
            app.SelectcompanydataButton = uibutton(app.InputPanel, 'push');
            app.SelectcompanydataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectcompanydataButtonPushed, true);
            app.SelectcompanydataButton.Text = 'Select company data';
            app.comapnyDataFile = uilabel(app.InputPanel);
            app.comapnyDataFile.Text = '';
            app.DefaultScoreEditFieldLabel = uilabel(app.InputPanel);
            app.DefaultScoreEditFieldLabel.Text = 'Default Score';
            app.DefaultScoreEditField = uieditfield(app.InputPanel, 'text');
            app.DefaultScoreEditField.ValueChangedFcn = createCallbackFcn(app, @DefaultScoreEditFieldValueChanged, true);
            app.DefaultScoreEditField.Value = '3.2';
            app.AggregationMethodDropDownLabel = uilabel(app.InputPanel);
            app.AggregationMethodDropDownLabel.Text = 'Aggregation Method';
            app.AggregationMethodDropDown = uidropdown(app.InputPanel);
            app.AggregationMethodDropDown.Items = {'WATS', 'TETS', 'AOTS', 'MOTS', 'EOTS', 'ECOTS', 'ROTS'};
            app.AggregationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @AggregationMethodDropDownValueChanged, true);
            app.AggregationMethodDropDown.Value = 'WATS';
            app.TimeframeListBoxLabel = uilabel(app.InputPanel);
            app.TimeframeListBoxLabel.Text = 'Time frame';
            app.TimeframeListBox = uilistbox(app.InputPanel);
            app.TimeframeListBox.Items = {'SHORT', 'MID', 'LONG'};
            app.TimeframeListBox.ItemsData = {'short', 'mid', 'long'};
            app.TimeframeListBox.Multiselect = 'on';
            app.TimeframeListBox.ValueChangedFcn = createCallbackFcn(app, @TimeframeListBoxValueChanged, true);
            app.TimeframeListBox.Value = {'short'};
            app.ScopeListBoxLabel = uilabel(app.InputPanel);
            app.ScopeListBoxLabel.Text = 'Scope';
            app.ScopeListBox = uilistbox(app.InputPanel);
            app.ScopeListBox.Items = {'S3', 'S1S2', 'S1S2S3'};
            app.ScopeListBox.ItemsData = {'S3', 'S1S2', 'S1S2S3'};
            app.ScopeListBox.Multiselect = 'on';
            app.ScopeListBox.ValueChangedFcn = createCallbackFcn(app, @ScopeListBoxValueChanged, true);
            app.ScopeListBox.Value = {'S3'};
            app.AggregatedTemperatureScorePanel = uipanel(app.TemperatureScoreTab);
            app.AggregatedTemperatureScorePanel.Title = 'Aggregated Temperature Score';
            app.Gauge = uigauge(app.AggregatedTemperatureScorePanel, 'semicircular');
            app.Gauge.Limits = [0 4];
            app.Gauge.MajorTicks = [0 1 2 3 4];
            app.TimeFrameDropDown_2Label = uilabel(app.AggregatedTemperatureScorePanel);
            app.TimeFrameDropDown_2Label.Text = 'Time Frame';
            app.AggTimeFrameDropDown = uidropdown(app.AggregatedTemperatureScorePanel);
            app.AggTimeFrameDropDown.Items = {'SHORT', 'MID', 'LONG'};
            app.AggTimeFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @AggTimeFrameDropDownValueChanged, true);
            app.AggTimeFrameDropDown.Value = 'SHORT';
            app.ScopeDropDown_2Label = uilabel(app.AggregatedTemperatureScorePanel);
            app.ScopeDropDown_2Label.Text = 'Scope';
            app.AggScopeDropDown = uidropdown(app.AggregatedTemperatureScorePanel);
            app.AggScopeDropDown.Items = {'S3', 'S1S2', 'S1S2S3'};
            app.AggScopeDropDown.ValueChangedFcn = createCallbackFcn(app, @AggScopeDropDownValueChanged, true);
            app.AggScopeDropDown.Value = 'S3';
            app.AggTemperatureScore = uilabel(app.AggregatedTemperatureScorePanel);
            app.AggTemperatureScore.Text = '0';
            app.PortfolioCoveragePanel = uipanel(app.TemperatureScoreTab);
            app.PortfolioCoveragePanel.Title = 'Portfolio Coverage';
            app.PortfolioCoverageValue = uilabel(app.PortfolioCoveragePanel);
            app.PortfolioCoverageValue.Text = '0';
            app.DisplaycolumnsPanel = uipanel(app.TemperatureScoreTab);
            app.DisplaycolumnsPanel.Title = 'Display columns';
            app.SelectColumnsListBox = uilistbox(app.DisplaycolumnsPanel);
            app.SelectColumnsListBox.Items = {};
            app.SelectColumnsListBox.Multiselect = 'on';
            app.SelectColumnsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectColumnsListBoxValueChanged, true);
            app.SelectColumnsListBox.Value = {};
            app.SectorRegionAnalysisTab = uitab(app.TabGroup);
            app.SectorRegionAnalysisTab.Title = 'Sector/Region Analysis';
            app.GroupScoreBarUIAxes = uiaxes(app.SectorRegionAnalysisTab);
            title(app.GroupScoreBarUIAxes, 'Temperature Score vs category')
            xlabel(app.GroupScoreBarUIAxes, 'Category')
            ylabel(app.GroupScoreBarUIAxes, 'Temperature Score')
            app.GroupScoreBarUIAxes.XTick = [];
            app.GroupScoreBarUIAxes.XTickLabel = '';
            app.GroupScoreBarUIAxes.YTick = [0 1 2 3 4];
            app.GroupScoreBarUIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'};
            app.PieInvest = uiaxes(app.SectorRegionAnalysisTab);
            title(app.PieInvest, 'Title')
            xlabel(app.PieInvest, 'X')
            ylabel(app.PieInvest, 'Y')
            zlabel(app.PieInvest, 'Z')
            app.PieContri = uiaxes(app.SectorRegionAnalysisTab);
            title(app.PieContri, 'Title')
            xlabel(app.PieContri, 'X')
            ylabel(app.PieContri, 'Y')
            zlabel(app.PieContri, 'Z')
            app.TimeFrameDropDownLabel = uilabel(app.SectorRegionAnalysisTab);
            app.TimeFrameDropDownLabel.Text = 'Time Frame';
            app.GroupTimeFrameDropDown = uidropdown(app.SectorRegionAnalysisTab);
            app.GroupTimeFrameDropDown.Items = {'SHORT', 'MID', 'LONG'};
            app.GroupTimeFrameDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupTimeFrameDropDownValueChanged, true);
            app.GroupTimeFrameDropDown.Value = 'SHORT';
            app.ScopeDropDownLabel = uilabel(app.SectorRegionAnalysisTab);
            app.ScopeDropDownLabel.Text = 'Scope';
            app.GroupScopeDropDown = uidropdown(app.SectorRegionAnalysisTab);
            app.GroupScopeDropDown.Items = {'S3', 'S1S2', 'S1S2S3'};
            app.GroupScopeDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupScopeDropDownValueChanged, true);
            app.GroupScopeDropDown.Value = 'S3';
            app.GroupbyDropDownLabel = uilabel(app.SectorRegionAnalysisTab);
            app.GroupbyDropDownLabel.Text = 'Group by';
            app.GroupbyDropDown = uidropdown(app.SectorRegionAnalysisTab);
            app.GroupbyDropDown.Items = {'region', 'sector'};
            app.GroupbyDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupbyDropDownValueChanged, true);
            app.GroupbyDropDown.Value = 'region';
            app.AggregatedTemperatureScorePanel_2 = uipanel(app.SectorRegionAnalysisTab);
            app.AggregatedTemperatureScorePanel_2.Title = 'Aggregated Temperature Score';
            app.groupGauge = uigauge(app.AggregatedTemperatureScorePanel_2, 'semicircular');
            app.groupGauge.Limits = [0 4];
            app.groupGauge.MajorTicks = [1 2 3 4];
            app.groupGauge.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.GroupAggTempScore = uilabel(app.AggregatedTemperatureScorePanel_2);
            app.GroupAggTempScore.Text = '0';
            app.HeatMap = uipanel(app.SectorRegionAnalysisTab);
            app.HeatMap.Title = 'Heatmap: Temperature score for region-sector';
            app.ScenarioAnalysisTab = uitab(app.TabGroup);
            app.ScenarioAnalysisTab.Title = 'Scenario Analysis';
            app.ScenarioInputsPanel = uipanel(app.ScenarioAnalysisTab);
            app.ScenarioInputsPanel.Title = 'Scenario Inputs';
            app.ScenarioNameEditFieldLabel = uilabel(app.ScenarioInputsPanel);
            app.ScenarioNameEditFieldLabel.Text = 'Scenario Name';
            app.ScenarioNameEditField = uieditfield(app.ScenarioInputsPanel, 'text');
            app.ScenarioNameEditField.Value = 'Scenario 1';
            app.ScenarioTypeDropDownLabel = uilabel(app.ScenarioInputsPanel);
            app.ScenarioTypeDropDownLabel.Text = 'Scenario Type';
            app.ScenarioTypeDropDown = uidropdown(app.ScenarioInputsPanel);
            app.ScenarioTypeDropDown.Items = {'TARGETS', 'APPROVED_TARGETS', 'HIGHEST_CONTRIBUTORS', 'HIGHEST_CONTRIBUTORS_APPROVED'};
            app.ScenarioTypeDropDown.Value = 'TARGETS';
            app.EngagementTypeDropDownLabel = uilabel(app.ScenarioInputsPanel);
            app.EngagementTypeDropDownLabel.Text = 'Engagement Type';
            app.EngagementTypeDropDown = uidropdown(app.ScenarioInputsPanel);
            app.EngagementTypeDropDown.Items = {'None', 'SET_TARGETS', 'SET_SBTI_TARGETS'};
            app.EngagementTypeDropDown.Value = 'None';
            app.AggregationMethodDropDown_2Label = uilabel(app.ScenarioInputsPanel);
            app.AggregationMethodDropDown_2Label.Text = 'Aggregation Method';
            app.AggregationMethodDropDown_2 = uidropdown(app.ScenarioInputsPanel);
            app.AggregationMethodDropDown_2.Items = {'WATS', 'TETS', 'AOTS', 'MOTS', 'EOTS', 'ECOTS', 'ROTS'};
            app.AggregationMethodDropDown_2.Value = 'WATS';
            app.AddScenarioButton = uibutton(app.ScenarioInputsPanel, 'push');
            app.AddScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @AddScenarioButtonPushed, true);
            app.AddScenarioButton.Text = 'Add Scenario';
            app.ScenariosPanel = uipanel(app.ScenarioAnalysisTab);
            app.ScenariosPanel.Title = 'Scenarios';
            app.ScenariosListBox = uilistbox(app.ScenariosPanel);
            app.ScenariosListBox.Items = {'Base'};
            app.ScenariosListBox.Multiselect = 'on';
            app.ScenariosListBox.Value = {'Base'};
            app.RemoveScenarioButton = uibutton(app.ScenariosPanel, 'push');
            app.RemoveScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveScenarioButtonPushed, true);
            app.RemoveScenarioButton.Text = 'Remove Scenario';
            app.ScenarioTable = uitable(app.ScenarioAnalysisTab);
            app.ScenarioTable.ColumnName = {'Scenario'; 'Scope'; 'Time_frame'; 'Temperature Score'};
            app.ScenarioTable.RowName = {};
            app.ExporttoWorkspaceScenarioButton = uibutton(app.ScenarioAnalysisTab, 'push');
            app.ExporttoWorkspaceScenarioButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceScenarioButtonPushed, true);
            app.ExporttoWorkspaceScenarioButton.Text = 'Export to Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SBTi_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end